classdef calculadora < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        detetado_num2                matlab.ui.control.Label
        detetado_op                  matlab.ui.control.Label
        detetado_num1                matlab.ui.control.Label
        CalcularButton               matlab.ui.control.Button
        OperadorLabel                matlab.ui.control.Label
        NmeroLabel_2                 matlab.ui.control.Label
        NmeroLabel                   matlab.ui.control.Label
        abririmagemButton_3          matlab.ui.control.Button
        abririmagemButton_2_OP       matlab.ui.control.Button
        abririmagemButton            matlab.ui.control.Button
        RESULTADO                    matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalculadoraInteligenteLabel  matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2_OP                  matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = public)
        fullname1 string
        fullname2 string
        fullname3
        fullname33 string
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: abririmagemButton
        function abririmagemButtonPushed(comp, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           comp.fullname1 = [filepath, filename];
           ImageFile1 = imread(comp.fullname1);
           imagesc(comp.UIAxes, ImageFile1);
        end

        % Button pushed function: abririmagemButton_2_OP
        function abririmagemButton_2_OPPushed(comp, event)
             [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           comp.fullname2 = [filepath, filename];
           ImageFile2 = imread(comp.fullname2 );
           imagesc(comp.UIAxes_2_OP, ImageFile2);
        end

        % Button pushed function: abririmagemButton_3
        function abririmagemButton_3Pushed(comp, event)
             [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           comp.fullname33 = [filepath, filename];
           ImageFile3 = imread(comp.fullname33);
           imagesc(comp.UIAxes_3, ImageFile3);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(comp, event)
            num1 =testSingleImage("num",comp.fullname1);
            op=testSingleImage("op",comp.fullname2);
            num2=testSingleImage("num",comp.fullname33);
            if(string(num1)=="Could not recognize" ||string(num2)=="Could not recognize" || string(op)=="Could not recognize")'
                comp.RESULTADO="Could not recognize one or more of the images";
            else
                comp.detetado_num1.Text=strcat("Detetado: ",string(num1));
                comp.detetado_op.Text=strcat("Detetado: ",string(op));
                comp.detetado_num2.Text=strcat("Detetado: ",string(num2));
            switch op
                case "+"
                    result= num1 + num2;
                case "-"
                    result = num1 - num2;
                case "/"
                    result = num1 / num2;
                case "*"
                    result = num1 * num2;

            end  
    comp.RESULTADO.Text =string(result);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying 

           
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1038 532];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes = uiaxes(comp);
            comp.UIAxes.XTick = [];
            comp.UIAxes.YTick = [];
            comp.UIAxes.ZTick = [];
            comp.UIAxes.Visible = 'off';
            comp.UIAxes_2_OP = uiaxes(comp);
            comp.UIAxes_2_OP.XTick = [];
            comp.UIAxes_2_OP.YTick = [];
            comp.UIAxes_2_OP.ZTick = [];
            comp.UIAxes_2_OP.Visible = 'off';
            comp.UIAxes_3 = uiaxes(comp);
            comp.UIAxes_3.XTick = [];
            comp.UIAxes_3.YTick = [];
            comp.UIAxes_3.ZTick = [];
            comp.UIAxes_3.Visible = 'off';
            comp.CalculadoraInteligenteLabel = uilabel(comp);
            comp.CalculadoraInteligenteLabel.Text = 'Calculadora Inteligente';
            comp.Image = uiimage(comp);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'calculator-695084_1280.png');
            comp.Label = uilabel(comp);
            comp.Label.Text = '=';
            comp.RESULTADO = uilabel(comp);
            comp.RESULTADO.Text = '';
            comp.abririmagemButton = uibutton(comp, 'push');
            comp.abririmagemButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @abririmagemButtonPushed, true);
            comp.abririmagemButton.Text = 'abrir imagem';
            comp.abririmagemButton_2_OP = uibutton(comp, 'push');
            comp.abririmagemButton_2_OP.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @abririmagemButton_2_OPPushed, true);
            comp.abririmagemButton_2_OP.Text = 'abrir imagem';
            comp.abririmagemButton_3 = uibutton(comp, 'push');
            comp.abririmagemButton_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @abririmagemButton_3Pushed, true);
            comp.abririmagemButton_3.Text = 'abrir imagem';
            comp.NmeroLabel = uilabel(comp);
            comp.NmeroLabel.Text = 'Número';
            comp.NmeroLabel_2 = uilabel(comp);
            comp.NmeroLabel_2.Text = 'Número';
            comp.OperadorLabel = uilabel(comp);
            comp.OperadorLabel.Text = 'Operador';
            comp.CalcularButton = uibutton(comp, 'push');
            comp.CalcularButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CalcularButtonPushed, true);
            comp.CalcularButton.Text = 'Calcular';
            comp.detetado_num1 = uilabel(comp);
            comp.detetado_num1.Text = '';
            comp.detetado_op = uilabel(comp);
            comp.detetado_op.Text = '';
            comp.detetado_num2 = uilabel(comp);
            comp.detetado_num2.Text = '';
        end
    end
end